classdef KruskalAppEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AlgoritmodeKruskalUIFigure  matlab.ui.Figure
        CargarmatrizdeadyacenciasButton  matlab.ui.control.Button
        CalcularrbolgeneradordepesomnimoButton  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename % nombre de archivo de la matriz de adyacencias
        A        % matriz de adyacencias importada
        G        % grafo original
        MST      % árbol generador de peso mínimo
        plt      % visualización de los grafos
    end
    
    methods (Access = private)
        
function MST = MinimumSpanTree(app, Graph)

% Iniciar Minimum Spanning Tree (MST) con arista de peso mínimo
MST = graph();

% Ordenar y obtener arista y peso de la arista del peso mínimo
minEdge = sortrows(Graph.Edges, 'Weight').EndNodes(1,:);
minWeight = sortrows(Graph.Edges, 'Weight').Weight(1,:);

MST = MST.addedge(minEdge(1), minEdge(2), minWeight);

% Eliminar vértice de Graph
Graph = Graph.rmedge(minEdge(1), minEdge(2));

% Mientras haya aristas en Graph, seguir iterando
while (~isempty(Graph.Edges))
    
    % subgráfica de G adyacente a MST
    subG = app.adjacentSubgraph(Graph, MST);
    
    % Arista de peso mínimo
    minEdge = sortrows(subG.Edges, 'Weight').EndNodes(1,:);
    minWeight = sortrows(subG.Edges, 'Weight').Weight(1,:);
    
    % Número de nodos de la arista que aparecen en el grafo
    N = app.countNodes(MST, minEdge);
    
    % Si ninguno o sólo uno de los nodos aparecen, agregar
    % la arista al árbol generador y borrarla de G
    if N < 2
       MST = MST.addedge(minEdge(1), minEdge(2), minWeight);
    end
    
    % Eliminar arista de G
    Graph = Graph.rmedge(minEdge(1), minEdge(2));
end

end


function subG = adjacentSubgraph(~, G, F)
% Obtiene la subgráfica de G con las aristas que incluyen a nodos en F
% F es una subgráfica de G

% Nodos de Graph y SubGraph
GNodes = unique(G.Edges.EndNodes);
FNodes = unique(F.Edges.EndNodes);

% Intersección de nodos
Nodes = intersect(GNodes, FNodes);

% Subgráfica resultante
subG = graph();

for i = 1:length(Nodes)
    
    % id's de aristas que incluyen a node
    eid = G.outedges(Nodes(i));
    
    % EdgeTable de las aristas que incluyen a node
    tEdges = G.Edges(eid,:);
    
    % Agregar aristas elegidas a subGraph
    subG = subG.addedge(tEdges);
end

end


function n = countNodes(~, G, edge)
% Cuenta el número de nodos de la arista que están en el grafo

% Conjunto de nodos en el grafo G
nodes = unique(G.Edges.EndNodes);

% Devuelve el máximo número de veces
n = sum(ismember(edge, nodes));

end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarmatrizdeadyacenciasButton
        function CargarmatrizdeadyacenciasButtonPushed(app, event)
            
            % Abrir cuadro de diálogo para elegir matriz
            [file, path] = uigetfile("*.csv");
            app.filename = strcat(path, file);
            
            % Cargar archivo en matriz A
            app.A = readmatrix(app.filename);
            
            % Crear grafo
            if istril(app.A)
                app.G = graph(app.A,'lower',"omitselfloops");
            elseif istriu(app.A)
                app.G = graph(app.A,'upper',"omitselfloops");
            else
                app.G = graph(app.A,"omitselfloops");
            end
            
            % Visualizar grafo con pesos
            app.plt = plot(app.UIAxes, app.G, 'EdgeLabel', app.G.Edges.Weight);
            
        end

        % Button pushed function: 
        % CalcularrbolgeneradordepesomnimoButton
        function CalcularrbolgeneradordepesomnimoButtonPushed(app, event)
            % Crear grafo del árbol generador de peso mínimo
            app.MST = app.MinimumSpanTree(app.G);
            
            % Visualizar árbol generador de peso mínimo
            highlight(app.plt, app.MST, 'EdgeColor', 'r', 'LineWidth', 1.5);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AlgoritmodeKruskalUIFigure = uifigure('Visible', 'off');
            app.AlgoritmodeKruskalUIFigure.Name = 'Algoritmo de Kruskal';
            app.CargarmatrizdeadyacenciasButton = uibutton(app.AlgoritmodeKruskalUIFigure, 'push');
            app.CargarmatrizdeadyacenciasButton.ButtonPushedFcn = createCallbackFcn(app, @CargarmatrizdeadyacenciasButtonPushed, true);
            app.CargarmatrizdeadyacenciasButton.Text = 'Cargar matriz de adyacencias';
            app.CalcularrbolgeneradordepesomnimoButton = uibutton(app.AlgoritmodeKruskalUIFigure, 'push');
            app.CalcularrbolgeneradordepesomnimoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularrbolgeneradordepesomnimoButtonPushed, true);
            app.CalcularrbolgeneradordepesomnimoButton.Text = 'Calcular árbol generador de peso mínimo';
            app.UIAxes = uiaxes(app.AlgoritmodeKruskalUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            % Show the figure after all components are created
            app.AlgoritmodeKruskalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KruskalAppEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AlgoritmodeKruskalUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AlgoritmodeKruskalUIFigure)
        end
    end
end